classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        hough_peaks                 matlab.ui.control.UIAxes
        RotationSliderLabel         matlab.ui.control.Label
        RotationSlider              matlab.ui.control.Slider
        xTranslationSliderLabel     matlab.ui.control.Label
        xTranslationSlider          matlab.ui.control.Slider
        yTranslationSliderLabel     matlab.ui.control.Label
        yTranslationSlider          matlab.ui.control.Slider
        norm_hough_peaks            matlab.ui.control.UIAxes
        laplacian_eigenvalues       matlab.ui.control.UIAxes
        img_axes                    matlab.ui.control.UIAxes
        SelectImageDropDownLabel    matlab.ui.control.Label
        SelectImageDropDown         matlab.ui.control.DropDown
        ResetTransformValuesButton  matlab.ui.control.Button
        FillValueEditFieldLabel     matlab.ui.control.Label
        FillValueEditField          matlab.ui.control.NumericEditField
        BoarderSzEditFieldLabel     matlab.ui.control.Label
        BoarderSzEditField          matlab.ui.control.NumericEditField
        xdimEditFieldLabel          matlab.ui.control.Label
        xdimEditField               matlab.ui.control.NumericEditField
        ydimEditFieldLabel          matlab.ui.control.Label
        ydimEditField               matlab.ui.control.NumericEditField
        ApplynewdimensionsButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        imgs % Description
        img_show_orig % Description
        trans_vec = [0, 0] % Description
        theta_trans % Description
        theta_tform = eye(3); % Description
        img_show % Description
        im_dims = [500, 500]; % Description
        fill_value = 255 % Description
        boarder_sz = 0 % Description
    end
    
    methods (Access = private)
        
        function viz_img(app)
            a2dtform = affine2d(app.theta_tform);
            centerOutput = affineOutputView(size(app.img_show_orig), a2dtform, 'BoundsStyle', ...
                'CenterOutput');
            if any(app.trans_vec)
                app.img_show = imtranslate(imwarp(app.img_show_orig, a2dtform, 'cubic', ...
                    'OutputView', centerOutput, 'FillValues', app.fill_value), app.trans_vec, ...
                    'FillValues', app.fill_value);
            else
                app.img_show = imwarp(app.img_show_orig, a2dtform, 'cubic', 'OutputView', ...
                    centerOutput, 'FillValues', app.fill_value);
            end
            imshow(app.img_show, 'Parent', app.img_axes);
        end
        
        function load_in_imgs(app)
            [app.imgs, lbls] = load_imgs_std_sz_lbls(fullfile(cd, "data"), "*.jpg", app.im_dims);
            app.SelectImageDropDown.Items = lbls;
            values = cell(length(lbls), 1);
            counter = 1;
            for i=1:length(lbls)
                values{i} = counter;
                counter = counter + 1;
            end
            app.SelectImageDropDown.ItemsData = values;
            value = app.SelectImageDropDown.Value;
            app.img_show_orig = squeeze(app.imgs(value, :, :, :));
        end
        
        function apply_rot_to_tform(app)
            cos_value = cos(app.theta_trans);
            sin_value = sin(app.theta_trans);
            app.theta_tform(1, 1) = cos_value;
            app.theta_tform(1, 2) = -sin_value;
            app.theta_tform(2, 1) = sin_value;
            app.theta_tform(2, 2) = cos_value;
        end
        
        function update_slider_ranges(app)
            app.xTranslationSlider.Limits = [-app.im_dims(2) - app.boarder_sz, app.im_dims(2) + app.boarder_sz];
            app.yTranslationSlider.Limits = [-app.im_dims(1) - app.boarder_sz, app.im_dims(1) + app.boarder_sz];
        end
        
        function change_boarder(app)
            selected = app.SelectImageDropDown.Value;
            app.img_show_orig = squeeze(app.imgs(selected, :, :, :));
            if length(size(app.img_show_orig)) == 3
                new_sz = [2*app.boarder_sz + app.im_dims(1), 2*app.boarder_sz + app.im_dims(2), 3];
                tmp = ones(new_sz, 'uint8').*app.fill_value;
                tmp(app.boarder_sz + 1:app.boarder_sz + app.im_dims(1), app.boarder_sz + 1:app.boarder_sz + app.im_dims(2), :) = app.img_show_orig;
            else
                new_sz = [2*app.boarder_sz + app.im_dims(1), 2*app.boarder_sz + app.im_dims(2)];
                tmp = ones(new_sz, 'uint8').*app.fill_value;
                tmp(app.boarder_sz + 1:app.boarder_sz + app.im_dims(1), app.boarder_sz + 1:app.boarder_sz + app.im_dims(2)) = app.img_show_orig;
            end
            
            app.img_show_orig = tmp;
            app.update_slider_ranges();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.load_in_imgs();
            app.viz_img();
            app.update_slider_ranges();
            app.FillValueEditField.Value = app.fill_value;
            app.xdimEditField.Value = app.im_dims(2);
            app.ydimEditField.Value = app.im_dims(1);
        end

        % Value changed function: RotationSlider
        function RotationSliderValueChanged(app, event)
            app.theta_trans = (event.Value * pi) / 180;
            app.apply_rot_to_tform();
            app.viz_img();
        end

        % Value changed function: SelectImageDropDown
        function SelectImageDropDownValueChanged(app, event)
            value = app.SelectImageDropDown.Value;
            app.img_show_orig = squeeze(app.imgs(value, :, :, :));
            app.change_boarder();
            app.viz_img();
        end

        % Value changed function: xTranslationSlider
        function xTranslationSliderValueChanged(app, event)
            app.trans_vec(1) = app.xTranslationSlider.Value;
            app.viz_img();
        end

        % Value changed function: yTranslationSlider
        function yTranslationSliderValueChanged(app, event)
            app.trans_vec(2) = app.yTranslationSlider.Value;
            app.viz_img();
        end

        % Value changing function: RotationSlider
        function RotationSliderValueChanging(app, event)
            app.theta_trans = (event.Value * pi) / 180;
            app.apply_rot_to_tform();
            app.viz_img;
        end

        % Value changing function: xTranslationSlider
        function xTranslationSliderValueChanging(app, event)
            app.trans_vec(1) = event.Value;
            app.viz_img();
        end

        % Value changing function: yTranslationSlider
        function yTranslationSliderValueChanging(app, event)
            app.trans_vec(2) = event.Value;
            app.viz_img();
        end

        % Value changed function: FillValueEditField
        function FillValueEditFieldValueChanged(app, event)
            app.fill_value = app.FillValueEditField.Value;
            app.viz_img();
        end

        % Button pushed function: ResetTransformValuesButton
        function ResetTransformValuesButtonPushed(app, event)
            app.theta_trans = 0;
            app.apply_rot_to_tform();
            app.trans_vec = [0, 0];
            app.RotationSlider.Value = 0;
            app.xTranslationSlider.Value = 0;
            app.yTranslationSlider.Value = 0;
            app.viz_img();
        end

        % Value changed function: BoarderSzEditField
        function BoarderSzEditFieldValueChanged(app, event)
            app.boarder_sz = app.BoarderSzEditField.Value;
            app.change_boarder();
            app.viz_img();
        end

        % Button pushed function: ApplynewdimensionsButton
        function ApplynewdimensionsButtonPushed(app, event)
            app.im_dims = [app.ydimEditField.Value, app.xdimEditField.Value];
            app.load_in_imgs();
            app.change_boarder();
            app.viz_img();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.hough_peaks = uiaxes(app.UIFigure);
            title(app.hough_peaks, 'Hough Peaks')
            xlabel(app.hough_peaks, '\theta')
            ylabel(app.hough_peaks, '\rho')
            app.RotationSliderLabel = uilabel(app.UIFigure);
            app.RotationSliderLabel.Text = 'Rotation';
            app.RotationSlider = uislider(app.UIFigure);
            app.RotationSlider.Limits = [-180 180];
            app.RotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationSliderValueChanged, true);
            app.RotationSlider.ValueChangingFcn = createCallbackFcn(app, @RotationSliderValueChanging, true);
            app.xTranslationSliderLabel = uilabel(app.UIFigure);
            app.xTranslationSliderLabel.Text = 'x Translation';
            app.xTranslationSlider = uislider(app.UIFigure);
            app.xTranslationSlider.Limits = [-1 1];
            app.xTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @xTranslationSliderValueChanged, true);
            app.xTranslationSlider.ValueChangingFcn = createCallbackFcn(app, @xTranslationSliderValueChanging, true);
            app.yTranslationSliderLabel = uilabel(app.UIFigure);
            app.yTranslationSliderLabel.Text = 'y Translation';
            app.yTranslationSlider = uislider(app.UIFigure);
            app.yTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @yTranslationSliderValueChanged, true);
            app.yTranslationSlider.ValueChangingFcn = createCallbackFcn(app, @yTranslationSliderValueChanging, true);
            app.norm_hough_peaks = uiaxes(app.UIFigure);
            title(app.norm_hough_peaks, 'Normalized Hough Peaks')
            xlabel(app.norm_hough_peaks, '\theta')
            ylabel(app.norm_hough_peaks, '\rho')
            app.laplacian_eigenvalues = uiaxes(app.UIFigure);
            title(app.laplacian_eigenvalues, 'Eigenvalues of Laplacian')
            xlabel(app.laplacian_eigenvalues, 'value')
            ylabel(app.laplacian_eigenvalues, 'count')
            app.img_axes = uiaxes(app.UIFigure);
            title(app.img_axes, 'Image')
            xlabel(app.img_axes, '')
            ylabel(app.img_axes, '')
            app.img_axes.XTick = [];
            app.img_axes.YTick = [];
            app.SelectImageDropDownLabel = uilabel(app.UIFigure);
            app.SelectImageDropDownLabel.Text = 'Select Image';
            app.SelectImageDropDown = uidropdown(app.UIFigure);
            app.SelectImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectImageDropDownValueChanged, true);
            app.ResetTransformValuesButton = uibutton(app.UIFigure, 'push');
            app.ResetTransformValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTransformValuesButtonPushed, true);
            app.ResetTransformValuesButton.Text = 'Reset Transform Values';
            app.FillValueEditFieldLabel = uilabel(app.UIFigure);
            app.FillValueEditFieldLabel.Text = 'Fill Value';
            app.FillValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.FillValueEditField.Limits = [0 255];
            app.FillValueEditField.ValueDisplayFormat = '%.0f';
            app.FillValueEditField.ValueChangedFcn = createCallbackFcn(app, @FillValueEditFieldValueChanged, true);
            app.FillValueEditField.Value = 255;
            app.BoarderSzEditFieldLabel = uilabel(app.UIFigure);
            app.BoarderSzEditFieldLabel.Text = 'Boarder Sz';
            app.BoarderSzEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoarderSzEditField.Limits = [0 1000];
            app.BoarderSzEditField.RoundFractionalValues = 'on';
            app.BoarderSzEditField.ValueDisplayFormat = '%.0f';
            app.BoarderSzEditField.ValueChangedFcn = createCallbackFcn(app, @BoarderSzEditFieldValueChanged, true);
            app.xdimEditFieldLabel = uilabel(app.UIFigure);
            app.xdimEditFieldLabel.Text = 'x dim';
            app.xdimEditField = uieditfield(app.UIFigure, 'numeric');
            app.xdimEditField.Limits = [0 2000];
            app.xdimEditField.ValueDisplayFormat = '%.0f';
            app.ydimEditFieldLabel = uilabel(app.UIFigure);
            app.ydimEditFieldLabel.Text = 'y dim';
            app.ydimEditField = uieditfield(app.UIFigure, 'numeric');
            app.ydimEditField.Limits = [0 2000];
            app.ydimEditField.ValueDisplayFormat = '%.0f';
            app.ApplynewdimensionsButton = uibutton(app.UIFigure, 'push');
            app.ApplynewdimensionsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplynewdimensionsButtonPushed, true);
            app.ApplynewdimensionsButton.Text = 'Apply new dimensions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end